classdef device_register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image5                        matlab.ui.control.Image
        Image4                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        TabGroup                      matlab.ui.container.TabGroup
        DevicesTab                    matlab.ui.container.Tab
        userPanel                     matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        SignedinasLabel               matlab.ui.control.Label
        lblUser                       matlab.ui.control.Label
        btnHLDDevice                  matlab.ui.control.Button
        btnBackDevice                 matlab.ui.control.Button
        Panel_7                       matlab.ui.container.Panel
        ShowglobaldevicesSwitch       matlab.ui.control.Switch
        ShowglobaldevicesSwitchLabel  matlab.ui.control.Label
        btnDeleteDevice               matlab.ui.control.Button
        ChooseadeviceLabel            matlab.ui.control.Label
        tbDevices                     matlab.ui.control.Table
        Panel_6                       matlab.ui.container.Panel
        CapturedeviceLabel            matlab.ui.control.Label
        btnRegister                   matlab.ui.control.Button
        txtModel                      matlab.ui.control.EditField
        ModelEditFieldLabel           matlab.ui.control.Label
        txtBrand                      matlab.ui.control.EditField
        BrandEditFieldLabel           matlab.ui.control.Label
        ImagesTab                     matlab.ui.container.Tab
        dpdDevice                     matlab.ui.control.DropDown
        DeviceDropDownLabel           matlab.ui.control.Label
        userPanel_2                   matlab.ui.container.Panel
        Image_2                       matlab.ui.control.Image
        SignedinasLabel_2             matlab.ui.control.Label
        lblUser_2                     matlab.ui.control.Label
        btnHLDImage                   matlab.ui.control.Button
        btnBackImage                  matlab.ui.control.Button
        Panel_11                      matlab.ui.container.Panel
        MetadataLabel                 matlab.ui.control.Label
        btnSearch                     matlab.ui.control.Button
        tbMetadata                    matlab.ui.control.Table
        Panel_10                      matlab.ui.container.Panel
        SourceImageLabel              matlab.ui.control.Label
        imgSource                     matlab.ui.control.Image
        btnLoadImage                  matlab.ui.control.Button
        Panel_9                       matlab.ui.container.Panel
        ManageimagesLabel             matlab.ui.control.Label
        btnRefreshImage               matlab.ui.control.Button
        btnDeleteImage                matlab.ui.control.Button
        tbImages                      matlab.ui.control.Table
    end

    properties (Access = private)
        user % User
        idUser % User id
        brand % Phone brand
        model % Phone model
        newDevice % New device Table
        conn % Connection
        query % Prepare query
        devices % Result of devices
        idSelected % Id of the selected device
        file
        path
        queryGetDevice
        lastFile
        dpdIdSelected
    end

    methods (Access = private)
        % Function that obtains all the devices from the database and
        % inserts them into tbDevices
        function getDevices(app)
            % Execute the query and get the results in app.devices
            app.devices = fetch(app.conn, app.queryGetDevice);
            % Insert the values of app.devices into tbDevices
            app.tbDevices.Data = app.devices;
            % 
            for i = 1:height(app.devices)
                app.dpdDevice.Items = strcat(string(app.devices.idDevice.'), ".- ", string(app.devices.brand.'), " ", string(app.devices.model.'));
                app.dpdIdSelected = app.devices.idDevice(1);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user)
            % Get user submission by previous page
            app.user = user;
            % Show the user in lblUser
            app.lblUser.Text = app.user;
            app.lblUser_2.Text = app.user;
            % Create the connection with database
            app.conn = database('conn_scih', 'root', 'root');
            % Prepare the statement with the username to get idUser
            app.query = strcat("SELECT idUser FROM User ", ...
                                    "WHERE username = '", app.user, "'");
            % Execute the query and get the results in app.idUser
            app.idUser = fetch(app.conn, app.query);
            % Prepare the statement with the idUser to only get User
            % Devices
            app.queryGetDevice = strcat("SELECT idDevice, brand, model, img_count FROM Device ", ...
                                    "WHERE idUser = ", string(app.idUser.idUser));
            % Call the function getDevices
            app.getDevices();
            disp(app.dpdIdSelected);
        end

        % Cell selection callback: tbDevices
        function tbDevicesCellSelection(app, event)
            % Get the selected index and with that get the id from the
            % app.devices object with the previously obtained index
            app.idSelected = app.devices.idDevice(event.Indices(1));
            disp(app.idSelected);
        end

        % Button pushed function: btnRegister
        function btnRegisterButtonPushed(app, event)
            % Get brand of txtBrand
            app.brand = app.txtBrand.Value;
            % Get model of txtModel
            app.model = app.txtModel.Value;
            if app.brand ~= ""
                if app.model ~= ""
                    % Create a new table to insert a new row in Device table in the
                    % database
                    app.newDevice = table(app.idUser.idUser, string(app.brand), string(app.model), 0, ...
                                    'VariableNames', {'idUser', 'brand', 'model', 'img_count'});
                    % The try tries to execute all the instructions, in case of
                    % an error it exit and shows the error obtaining it with the
                    % catch
                    try 
                        % Try write in database
                        sqlwrite(app.conn, 'Device', app.newDevice);
                        % Show a msgbox to confirm the write
                        msgbox("Device register succesfull", "Information", "help");
                        % Clean the TextBoxs
                        app.txtBrand.Value = "";
                        app.txtModel.Value = "";
                    catch e
                        % Show a msgbox to show the error
                        msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                    end
                    % Call the function getDevices
                    app.getDevices();
                else
                    msgbox("Please fill the Model", "Warning", "warn");
                end
            else
                msgbox("Please fill the Brand", "Warning", "warn");
            end
        end

        % Callback function: not associated with a component
        function btnRefreshDeviceButtonPushed(app, event)
            % ask if it is necessary because it is not used ....
            app.getDevices();
        end

        % Button pushed function: btnDeleteDevice
        function btnDeleteDeviceButtonPushed(app, event)
            % Prepare the statement with the idDevice to delete an device
            app.query = strcat("DELETE FROM Device ", ...
                                    "WHERE idDevice = ", string(app.idSelected));
             % The try tries to execute all the instructions, in case of
            % an error it exit and shows the error obtaining it with the
            % catch
            try
                % Execute the query and get the results in app.devices
                app.devices = fetch(app.conn, app.query);
            catch e
                % Show a msgbox to show the error
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Call the function getDevices 
            app.getDevices();
        end

        % Callback function: btnLoadImage, imgSource
        function btnLoadImageButtonPushed(app, event)
            [app.file, app.path] = uigetfile('*png; *.jpg; *.jpeg; *.HEIC', 'Select One or More Files', 'MultiSelect', 'on');
            if iscell(app.file)
                app.lastFile = "";
                for i = 1:width(app.file)
                    img = imread(strcat(string(app.path),string(app.file(i))));
                    save(strcat(".\images\ReferenceImage\", string(app.file(i))), 'img');
                    newRImage = table(app.dpdIdSelected, app.idUser.idUser, string(app.file(i)), ".\images\ReferenceImage\", "test", "test", "test", "test", "test", "test", ...
                                    'VariableNames', {'idDevice' 'idUser' 'file' 'path' 'des' 'orig' 'cam' 'adv_photo' 'gps' 'arch'});
                    % add 1 more to counter img_count
                    disp(newRImage);
                    try
                        % Try write in database
                        sqlwrite(app.conn, 'ReferenceImage', newRImage);
                    catch e
                        % Show a msgbox to show the error
                        msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                    end
                    app.lastFile = string(app.file(i));
                end
                app.imgSource.ImageSource = strcat(app.path,app.lastFile);
                % Show a msgbox to confirm the write
                msgbox("References Images register succesfull", "Information", "help");
            else
                img = imread(strcat(string(app.path),string(app.file)));
                save(strcat(".\images\ReferenceImage\", string(app.file)), 'img');
                newRImage = table(app.dpdIdSelected, app.idUser.idUser, string(app.file), strcat(".\images\ReferenceImage\"), "test", "test", "test", "test", "test", "test", ...
                                'VariableNames', {'idDevice' 'idUser' 'file' 'path' 'des' 'orig' 'cam' 'adv_photo' 'gps' 'arch'});
                % add 1 more to counter img_count
                disp(newRImage);
                try
                    % Try write in database
                    sqlwrite(app.conn, 'ReferenceImage', newRImage);
                    % Show a msgbox to confirm the write
                    msgbox("Reference Image register succesfull", "Information", "help");
                catch e
                    % Show a msgbox to show the error
                    msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                end
                app.imgSource.ImageSource = strcat(app.path,app.file);
            end
        end

        % Button pushed function: btnBackDevice, btnBackImage
        function btnBackDeviceButtonPushed(app, event)
            admin_view(app.user);
            delete(app);
        end

        % Value changed function: ShowglobaldevicesSwitch
        function ShowglobaldevicesSwitchValueChanged(app, event)
            if string(app.ShowglobaldevicesSwitch.Value) == "On"
                % Prepare the statement with the idUser to only get User
                % Devices
                app.queryGetDevice = strcat("SELECT idDevice, brand, model, img_count FROM Device");
            else
                % Prepare the statement with the idUser to only get User
                % Devices
                app.queryGetDevice = strcat("SELECT idDevice, brand, model, img_count FROM Device ", ...
                                        "WHERE idUser = ", string(app.idUser.idUser));
            end    
            app.getDevices();
        end

        % Value changed function: dpdDevice
        function dpdDeviceValueChanged(app, event)
            temp = split(app.dpdDevice.Value, ".");
            app.dpdIdSelected = temp(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DevicesTab = uitab(app.TabGroup);
            app.DevicesTab.Title = 'Devices';
            app.Panel_6 = uipanel(app.DevicesTab);
            app.BrandEditFieldLabel = uilabel(app.Panel_6);
            app.BrandEditFieldLabel.Text = 'Brand';
            app.txtBrand = uieditfield(app.Panel_6, 'text');
            app.ModelEditFieldLabel = uilabel(app.Panel_6);
            app.ModelEditFieldLabel.Text = 'Model';
            app.txtModel = uieditfield(app.Panel_6, 'text');
            app.btnRegister = uibutton(app.Panel_6, 'push');
            app.btnRegister.ButtonPushedFcn = createCallbackFcn(app, @btnRegisterButtonPushed, true);
            app.btnRegister.Text = 'Register';
            app.CapturedeviceLabel = uilabel(app.Panel_6);
            app.CapturedeviceLabel.Text = 'Capture device';
            app.Panel_7 = uipanel(app.DevicesTab);
            app.tbDevices = uitable(app.Panel_7);
            app.tbDevices.ColumnName = {'idDevice'; 'Brand'; 'Model'; 'Image #'};
            app.tbDevices.RowName = {};
            app.tbDevices.CellSelectionCallback = createCallbackFcn(app, @tbDevicesCellSelection, true);
            app.ChooseadeviceLabel = uilabel(app.Panel_7);
            app.ChooseadeviceLabel.Text = 'Choose a device';
            app.btnDeleteDevice = uibutton(app.Panel_7, 'push');
            app.btnDeleteDevice.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteDeviceButtonPushed, true);
            app.btnDeleteDevice.Text = 'Delete';
            app.ShowglobaldevicesSwitchLabel = uilabel(app.Panel_7);
            app.ShowglobaldevicesSwitchLabel.Text = 'Show global devices';
            app.ShowglobaldevicesSwitch = uiswitch(app.Panel_7, 'slider');
            app.ShowglobaldevicesSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowglobaldevicesSwitchValueChanged, true);
            app.btnBackDevice = uibutton(app.DevicesTab, 'push');
            app.btnBackDevice.ButtonPushedFcn = createCallbackFcn(app, @btnBackDeviceButtonPushed, true);
            app.btnBackDevice.Text = 'Back';
            app.btnHLDDevice = uibutton(app.DevicesTab, 'push');
            app.btnHLDDevice.Text = 'HLD';
            app.userPanel = uipanel(app.DevicesTab);
            app.lblUser = uilabel(app.userPanel);
            app.lblUser.Text = 'User Admin';
            app.SignedinasLabel = uilabel(app.userPanel);
            app.SignedinasLabel.Text = 'Signed in as: ';
            app.Image = uiimage(app.userPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.ImagesTab = uitab(app.TabGroup);
            app.ImagesTab.Title = 'Images';
            app.Panel_9 = uipanel(app.ImagesTab);
            app.tbImages = uitable(app.Panel_9);
            app.tbImages.ColumnName = {'Image''s name'};
            app.tbImages.RowName = {};
            app.tbImages.Multiselect = 'off';
            app.btnDeleteImage = uibutton(app.Panel_9, 'push');
            app.btnDeleteImage.Text = 'Delete';
            app.btnRefreshImage = uibutton(app.Panel_9, 'push');
            app.btnRefreshImage.Text = 'Refresh';
            app.ManageimagesLabel = uilabel(app.Panel_9);
            app.ManageimagesLabel.Text = 'Manage images';
            app.Panel_10 = uipanel(app.ImagesTab);
            app.btnLoadImage = uibutton(app.Panel_10, 'push');
            app.btnLoadImage.ButtonPushedFcn = createCallbackFcn(app, @btnLoadImageButtonPushed, true);
            app.btnLoadImage.Text = 'Load Image';
            app.imgSource = uiimage(app.Panel_10);
            app.imgSource.ImageClickedFcn = createCallbackFcn(app, @btnLoadImageButtonPushed, true);
            app.SourceImageLabel = uilabel(app.Panel_10);
            app.SourceImageLabel.Text = 'Source Image';
            app.Panel_11 = uipanel(app.ImagesTab);
            app.tbMetadata = uitable(app.Panel_11);
            app.tbMetadata.ColumnName = {''; ''};
            app.tbMetadata.RowName = {'''Description'';'; '''Source'';'; '''Image'';'; '''Camera'';'; '''Advance photo'';'; '''GPS'';'; '''File'';'};
            app.tbMetadata.Multiselect = 'off';
            app.btnSearch = uibutton(app.Panel_11, 'push');
            app.btnSearch.Text = 'Search';
            app.MetadataLabel = uilabel(app.Panel_11);
            app.MetadataLabel.Text = 'Metadata';
            app.btnBackImage = uibutton(app.ImagesTab, 'push');
            app.btnBackImage.ButtonPushedFcn = createCallbackFcn(app, @btnBackDeviceButtonPushed, true);
            app.btnBackImage.Text = 'Back';
            app.btnHLDImage = uibutton(app.ImagesTab, 'push');
            app.btnHLDImage.Text = 'HLD';
            app.userPanel_2 = uipanel(app.ImagesTab);
            app.lblUser_2 = uilabel(app.userPanel_2);
            app.lblUser_2.Text = 'User Admin';
            app.SignedinasLabel_2 = uilabel(app.userPanel_2);
            app.SignedinasLabel_2.Text = 'Signed in as: ';
            app.Image_2 = uiimage(app.userPanel_2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.DeviceDropDownLabel = uilabel(app.ImagesTab);
            app.DeviceDropDownLabel.Text = 'Device';
            app.dpdDevice = uidropdown(app.ImagesTab);
            app.dpdDevice.Items = {};
            app.dpdDevice.ValueChangedFcn = createCallbackFcn(app, @dpdDeviceValueChanged, true);
            app.dpdDevice.Value = {};
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = device_register(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end