classdef user_register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        btnRegister             matlab.ui.control.Button
        bgUserType              matlab.ui.container.ButtonGroup
        opAdmin                 matlab.ui.control.RadioButton
        opBasic                 matlab.ui.control.RadioButton
        Panel_2                 matlab.ui.container.Panel
        Image5                  matlab.ui.control.Image
        Image4                  matlab.ui.control.Image
        SignedinasLabel         matlab.ui.control.Label
        lblUser                 matlab.ui.control.Label
        FullnameEditFieldLabel  matlab.ui.control.Label
        txtName                 matlab.ui.control.EditField
        EmailEditFieldLabel     matlab.ui.control.Label
        txtEmail                matlab.ui.control.EditField
        txtPassword             matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        UsernameEditFieldLabel  matlab.ui.control.Label
        txtUsername             matlab.ui.control.EditField
        lblTitle                matlab.ui.control.Label
        SourcecameraidentificationbyHellingerdistanceLabel  matlab.ui.control.Label
        Image3                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        btnBack                 matlab.ui.control.Button
        btnHDL                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        user % Description
        username % Description
        password % Description
        email % Description
        name % Description
        type % Description
        newAdmin % Description
        conn % Connector ODBC
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user)
            app.user = user;
            app.lblUser.Text  = app.user;
            app.conn = database('conn_scih', 'root', 'root');
        end

        % Button pushed function: btnBack
        function btnBackButtonPushed(app, event)
            admin_view(app.user);
            delete(app);
        end

        % Button pushed function: btnRegister
        function btnRegisterButtonPushed(app, event)
            app.username = app.txtUsername.Value;
            app.password = app.txtPassword.Value;
            app.email = app.txtEmail.Value;
            app.name = app.txtName.Value;

            if app.txtUsername.Value == null || app.txtPassword.Value == null || app.txtEmail.Value == null || app.txtName.Value == null
                msgbox("Rellena los perro", "Warning", "error");
            else 
                if app.opAdmin.Value
                    app.type = "admin";
                else 
                    app.type = "basic";
                end
                
                app.newAdmin = table(string(app.username), string(app.password), string(app.type), string(app.name), string(app.email), ...
                'VariableNames', {'username', 'password', 'type', 'name', 'email'});
                try 
                    sqlwrite(app.conn, 'User', app.newAdmin);
                    msgbox("User register succesfull", "Information", "help");
                catch e
                   msgbox(strcat("Error", char(e.message)), "Warnign", "error");
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.btnHDL = uibutton(app.UIFigure, 'push');
            app.btnHDL.Text = 'HLD';
            app.btnBack = uibutton(app.UIFigure, 'push');
            app.btnBack.ButtonPushedFcn = createCallbackFcn(app, @btnBackButtonPushed, true);
            app.btnBack.Text = 'Back';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.SourcecameraidentificationbyHellingerdistanceLabel = uilabel(app.UIFigure);
            app.SourcecameraidentificationbyHellingerdistanceLabel.Text = 'Source camera identification by Hellinger distance';
            app.Panel = uipanel(app.UIFigure);
            app.lblTitle = uilabel(app.Panel);
            app.lblTitle.Text = 'Register user';
            app.txtUsername = uieditfield(app.Panel, 'text');
            app.UsernameEditFieldLabel = uilabel(app.Panel);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.PasswordEditFieldLabel = uilabel(app.Panel);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.txtPassword = uieditfield(app.Panel, 'text');
            app.txtEmail = uieditfield(app.Panel, 'text');
            app.EmailEditFieldLabel = uilabel(app.Panel);
            app.EmailEditFieldLabel.Text = 'E-mail';
            app.txtName = uieditfield(app.Panel, 'text');
            app.FullnameEditFieldLabel = uilabel(app.Panel);
            app.FullnameEditFieldLabel.Text = 'Full name';
            app.Panel_2 = uipanel(app.Panel);
            app.lblUser = uilabel(app.Panel_2);
            app.lblUser.Text = 'User Admin';
            app.SignedinasLabel = uilabel(app.Panel_2);
            app.SignedinasLabel.Text = 'Signed in as: ';
            app.Image4 = uiimage(app.Panel_2);
            app.Image5 = uiimage(app.Panel_2);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.bgUserType = uibuttongroup(app.Panel);
            app.bgUserType.Title = 'User type';
            app.opBasic = uiradiobutton(app.bgUserType);
            app.opBasic.Text = 'Basic';
            app.opAdmin = uiradiobutton(app.bgUserType);
            app.opAdmin.Text = 'Admin';
            app.opAdmin.Value = true;
            app.btnRegister = uibutton(app.Panel, 'push');
            app.btnRegister.ButtonPushedFcn = createCallbackFcn(app, @btnRegisterButtonPushed, true);
            app.btnRegister.Text = 'Register';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = user_register(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end